classdef BreathingSensor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BreathingSensorUIFigure        matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        RateHzLabel                    matlab.ui.control.Label
        SampleRateEditField            matlab.ui.control.NumericEditField
        TimesEditFieldLabel            matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        UpperEditFieldLabel            matlab.ui.control.Label
        UpperEditField                 matlab.ui.control.NumericEditField
        LowerEditFieldLabel            matlab.ui.control.Label
        LowerEditField                 matlab.ui.control.NumericEditField
        AutoRangeCheckBox              matlab.ui.control.CheckBox
        FileEditFieldLabel             matlab.ui.control.Label
        FileEditField                  matlab.ui.control.EditField
        FileSelectButton               matlab.ui.control.Button
        PathTextAreaLabel              matlab.ui.control.Label
        PathTextArea                   matlab.ui.control.TextArea
        PeakProminenceVEditFieldLabel  matlab.ui.control.Label
        PeakProminenceVEditField       matlab.ui.control.NumericEditField
        MaxPeaksperMinuteLabel         matlab.ui.control.Label
        MaxBreathingRateEditField      matlab.ui.control.NumericEditField
        InvertCheckBox                 matlab.ui.control.CheckBox
        BreathingRateEditFieldLabel    matlab.ui.control.Label
        BreathingRateEditField         matlab.ui.control.NumericEditField
        StartButton                    matlab.ui.control.StateButton
        SavetoFileCheckBox             matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Rate % sampling rate for Arduino, limited to 5kHz
        Data % data collected from sensor by Arduino
        Time % time data for collection
        Trace % Description
        FID % Description
        Arduino % Description
    end
    
    methods (Access = private)
        
        function recordData(app,src,~)
            counts = app.Arduino.BytesAvailableFcnCount/2;
            rawpacket = fread(src,counts,'uint16');
            fwrite(app.FID,uint16(rawpacket),'uint16');
            packet = rawpacket*5/1023;
            points = app.TimeEditField.Value*app.Rate;
            sizeLim = 90*app.Rate;
            if isempty(app.Data)
                app.Data = packet;
                app.Time = (1:(counts))/app.Rate;
                app.Time = app.Time(:);
            else
                app.Data = [app.Data; packet];
                timePacket = app.Time(end)+(1:counts)/app.Rate;
                app.Time = [app.Time; timePacket(:)];
            end
            L = length(app.Data);
            if L > points
                signal = app.Data(end-points+1:end);
                time = app.Time(end-points+1:end);
                app.UIAxes.XLim = [time(1) time(end)];
            else
                signal = app.Data;
                time = app.Time;
                app.UIAxes.XLim = [0 app.TimeEditField.Value];
            end
            if L>sizeLim
                app.Data = app.Data(end-sizeLim*60/90+1:end);
            end
            if app.InvertCheckBox.Value
                signal = 5-signal;
            end
            set(app.Trace,'XData',time,'YData',signal);
            
            if not(rem(L,app.Rate))
                zbase = ones(1,round(0.3*app.Rate))/round(0.3*app.Rate);
                signal = signal-filter(zbase,1,signal-mean(signal));
                peakDistance = app.Rate*60/app.MaxBreathingRateEditField.Value;
                prominence = app.PeakProminenceVEditField.Value;
                [~,locs] = findpeaks(signal,'MinPeakDistance',peakDistance,'MinPeakProminence',prominence);
                if numel(locs)>=2
                    RR = mean(60*app.Rate./diff(locs));
                    app.BreathingRateEditField.Value = RR;
                else
                    app.BreathingRateEditField.Value = 0;
                end
            end
        end
        
        function plotData(app,src,~)
            counts = app.Arduino.BytesAvailableFcnCount/2;
            packet = fread(src,counts,'uint16')*5/1023;
            points = app.TimeEditField.Value*app.Rate;
            sizeLim = 90*app.Rate;
            if isempty(app.Data)
                app.Data = packet(:);
                app.Time = (1:(counts))/app.Rate;
                app.Time = app.Time(:);
            else
                app.Data = [app.Data; packet(:)];
                timePacket = app.Time(end)+(1:counts)/app.Rate;
                app.Time = [app.Time; timePacket(:)];
            end
            L = length(app.Data);
            if L>points
                signal = app.Data(end-points+1:end);
                time = app.Time(end-points+1:end);
                app.UIAxes.XLim = [time(1) time(end)];
            else
                signal = app.Data;
                time = app.Time;
                app.UIAxes.XLim = [0 app.TimeEditField.Value];
            end
            if L>sizeLim
                app.Data = app.Data(end-sizeLim*60/90+1:end,:);
            end
            if app.InvertCheckBox.Value
                signal = 5-signal;
            end
            set(app.Trace,'XData',time,'YData',signal);
            
            if not(rem(L,app.Rate))
                zbase = ones(1,round(0.3*app.Rate))/round(0.3*app.Rate);
                signal = signal-filter(zbase,1,signal-mean(signal));
                peakDistance = app.Rate*60/app.MaxBreathingRateEditField.Value;
                prominence = app.PeakProminenceVEditField.Value;
                [~,locs] = findpeaks(signal,'MinPeakDistance',peakDistance,'MinPeakProminence',prominence);
                if numel(locs)>=2
                    RR = mean(60*app.Rate./diff(locs));
                    app.BreathingRateEditField.Value = RR;
                else
                    app.BreathingRateEditField.Value = 0;
                end
            end
        end
        
    end
    
    methods(Static)
        function result = getSpecialFolder(arg)
            specialFolderType = System.Type.GetType(...
                'System.Environment+SpecialFoder');
            folders = System.Enum.GetValues(specialFolderType);
            enumArg = [];
            for i=1:folders.length
                if strcmp(char(folders(i)),arg)
                    enumArg = folders(i);
                    break;
                end
            end
            if isempty(enumArg)
                error('Invalid Argument');
            end
            result = System.Environment.GetFolderPath(enumArg);
        end
        function devices = IDSerialComs()
            devices = [];
            Skey = 'HKEY_LOCAL_MACHINE\HARDWARE\DEVICEMAP\SERIALCOMM';
            [~, list] = dos(['REG QUERY ' Skey]);
            if ischar(list) && strcmp('ERROR',list(1:5))
                return;
            end
            list = strread(list,'%s','delimiter',' '); %#ok<FPARK> requires strread()
            coms = 0;
            for i = 1:numel(list)
                if strcmp(list{i}(1:3),'COM')
                    if ~iscell(coms)
                        coms = list(i);
                    else
                        coms{end+1} = list{i}; %#ok<AGROW> Loop size is always small
                    end
                end
            end
            key = 'HKEY_LOCAL_MACHINE\SYSTEM\CurrentControlSet\Enum\USB\';
            [~, vals] = dos(['REG QUERY ' key ' /s /f "FriendlyName" /t "REG_SZ"']);
            if ischar(vals) && strcmp('ERROR',vals(1:5))
                return;
            end
            vals = textscan(vals,'%s','delimiter','\t');
            vals = cat(1,vals{:});
            out = 0;
            for i = 1:numel(vals)
                if strcmp(vals{i}(1:min(12,end)),'FriendlyName')
                    if ~iscell(out)
                        out = vals(i);
                    else
                        out{end+1} = vals{i}; %#ok<AGROW> Loop size is always small
                    end
                end
            end
            for i = 1:numel(coms)
                match = strfind(out,[coms{i},')']);
                ind = 0;
                for j = 1:numel(match)
                    if ~isempty(match{j})
                        ind = j;
                    end
                end
                if ind ~= 0
                    com = str2double(coms{i}(4:end));
                    if com > 9
                        length = 8;
                    else
                        length = 7;
                    end
                    devices{i,1} = out{ind}(27:end-length); %#ok<AGROW>
                    devices{i,2} = com; %#ok<AGROW> Loop size is always small
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
            correct_port = [];
            while isempty(correct_port)
                Comms = app.IDSerialComs();
                Comms = reshape(Comms(~cellfun(@isempty,Comms)),[],2);
                while isempty(Comms)
                    val = uiconfirm(app.BreathingSensorUIFigure,'No Arduino detected.','Breathing Sensor','Options',{'Try again','Cancel'},'Icon','Warning');
                    if strcmp(val,'Try again')
                        Comms = app.IDSerialComs();
                    else
                        delete(app);
                        return
                    end
                end
                ArduinoPorts = [Comms{(contains(Comms(:,1),'Arduino')),2}];
                N = numel(ArduinoPorts);
                Program = cell(N,1);
                for n=1:N
                    arduino = serial(['COM' int2str(ArduinoPorts(n))]); %#ok<*TNMLP>
                    arduino.BaudRate = 115200;
                    arduino.Terminator = [];
                    try
                    fopen(arduino);
                    attempts = 0; connection = false;
                    pause(0.1);
                    while attempts < 50 && connection == false
                        fwrite(arduino,0);
                        tic;
                        while ~arduino.BytesAvailable && toc<0.1
                            drawnow;
                        end
                        if toc>0.1
                            attempts = attempts+1;
                        else
                            pause(0.1);
                            if arduino.BytesAvailable>5
                                Program{n} = char(fread(arduino,arduino.BytesAvailable))'; %#ok<*FREAD>
                                connection = true;
                            end
                        end
                    end
                    if ~connection
                        Program{n} = 'No program';
                    end
                    fclose(arduino);
                    catch
                        Program{n} = 'In Use';
                    end
                end
                correct_port = find(contains(Program,'Breathing Sensor'));
                if isempty(correct_port)
                    selection = uiconfirm(app.BreathingSensorUIFigure,'No matching Arduino programs found. Try connecting again?','Breathing Sensor','Options',{'Try again','Cancel'},'Icon','Error');
                    if strcmp(selection,'Cancel') || isempty(selection)
                        delete(app);
                        return
                    end
                end
            end
            app.Arduino = serial(['COM' int2str(ArduinoPorts(correct_port))]);
            app.Arduino.BaudRate = 115200;
            app.Arduino.Terminator = [];
            app.Arduino.BytesAvailableFcnMode = 'byte';
            app.Arduino.BytesAvailableFcnCount = 200;
            app.Arduino.BytesAvailableFcn = @(src,event) plotData(app,src,event);
            fopen(app.Arduino);
            
            app.Rate = app.SampleRateEditField.Value;
            app.Trace = line(app.UIAxes,0,0,'Color','b','LineWidth',1);
            app.PathTextArea.Value = [getSpecialFolder('Desktop'),filesep];
        end

        % Value changed function: SampleRateEditField
        function SetSampleRate(app, event)
            value = app.SampleRateEditField.Value;
            app.Rate = value;
            fclose(app.Arduino);
            app.Arduino.BytesAvailableFcnCount = min(max(2,round(value/10)*2),400);
            fopen(app.Arduino);
        end

        % Value changed function: StartButton
        function StartStopRecording(app, event)
            value = app.StartButton.Value;
            if value
                if app.SavetoFileCheckBox.Value
                    if isempty(app.FileEditField.Value)
                        questdlg('Please enter a name for the file or deselect the record to file option','Breathing Sensor','Ok','Ok');
                        app.StartButton.Value = false;
                        return;
                    end
                    filename = [app.PathTextArea.Value{:} app.FileEditField.Value '.bin'];
                    if exist(filename,'file')
                        q = questdlg('A file with the same name already exists. Overwrite?','Breathing Sensor','Yes','No','No');
                        if not(strcmp(q,'Yes'))
                            app.StartButton.Value = false;
                            app.StartButton.Text = 'Start';
                            app.SavetoFileCheckBox.Enable = true;
                            app.SampleRateEditField.Enable = true;
                            return
                        end
                    end
                    app.FID = fopen(filename,'w');
                    fwrite(app.FID,now,'double');
                    fwrite(app.FID,app.Rate,'double');
                end
                % diable all that could cause an error
                app.SavetoFileCheckBox.Enable = false;
                app.SampleRateEditField.Enable = false;
                app.StartButton.Text = 'Stop';
                % reset time and data vectors
                app.Data = [];
                app.Time = [];
                % send interval spacing between samples in microseconds
                if app.Arduino.BytesAvailable
                    fread(app.Arduino,app.Arduino.BytesAvailable);
                end
                correct_interval = false;
                while ~correct_interval
                    us = ceil(1e6/app.Rate);
                    fwrite(app.Arduino,[1 fliplr(double(typecast(uint32(us),'uint8')))]);
                    pause(0.1);
                    if app.Arduino.BytesAvailable
                        output = char(fread(app.Arduino,app.Arduino.BytesAvailable))';
                        interval = str2double(output);
                        if interval == us
                            correct_interval = true;
                        else
                            disp('Wrong')
                            if app.Arduino.BytesAvailable
                                char(fread(app.Arduino,app.Arduino.BytesAvailable));
                            end
                        end
                    end
                end
                fwrite(app.Arduino,2); % command to start recording
            else
                fwrite(app.Arduino,3);
                pause(0.1);
                if app.Arduino.BytesAvailable
                    counts = app.Arduino.BytesAvailable/2;
                    packet = [];
                    if numel(counts)>1
                        packet = fread(app.Arduino,counts,'uint16');
                        timePacket = app.Time(end)+(1:counts)/app.Rate;
                        app.Data = [app.Data; packet(:)];
                        app.Time = [app.Time; timePacket(:)];
                        fwrite(app.FID,uint16(packet),'uint16');
                    end
                    if app.SavetoFileCheckBox.Value
                        if ~isempty(packet)
                            fwrite(app.FID,uint16(packet),'uint16');
                        end
                        fclose(app.FID);
                    end
                end
                app.SavetoFileCheckBox.Enable = true;
                app.SampleRateEditField.Enable = true;
                app.StartButton.Text = 'Start';
            end
        end

        % Value changed function: LowerEditField, UpperEditField
        function SetYRange(app, event)
            value(2) = round(app.UpperEditField.Value,2);
            if value(2)>5.1,value(2) = 5.1;end
            value(1) = round(app.LowerEditField.Value,2);
            if value(1)<-0.1,value(1) = -0.1; end
            if value(1)>=value(2)
                app.LowerEditField.Value = app.UIAxes.YLim(1);
                app.UpperEditField.Value = app.UIAxes.YLim(2);
            else
                app.UIAxes.YLim = value;
                app.UIAxes.YLimMode = 'manual';
                app.AutoRangeCheckBox.Value = false;
            end
        end

        % Value changed function: AutoRangeCheckBox
        function AutoRangeYAxis(app, event)
            value = app.AutoRangeCheckBox.Value;
            if value
                app.UIAxes.YLimMode = 'auto';
            else
                app.UIAxes.YLimMode = 'manual';
                app.UIAxes.YLim = [app.LowerEditField.Value app.UpperEditField.Value];
            end
        end

        % Button pushed function: FileSelectButton
        function ChooseFile(app, event)
            [file,path] = uiputfile('.bin');
            if file
                app.PathTextArea.Value = path;
                if numel(file)>4
                    app.FileEditField.Value = file(1:end-4);
                end
            end
        end

        % Value changed function: SavetoFileCheckBox
        function ChangeCallback(app, event)
            fclose(app.Arduino);
            pause(0.1);
            value = app.SavetoFileCheckBox.Value;
            if value
                app.Arduino.BytesAvailableFcn = @(src,event) recordData(app,src,event);
            else
                app.Arduino.BytesAvailableFcn = @(src,event) plotData(app,src,event);
            end
            fopen(app.Arduino);
        end

        % Close request function: BreathingSensorUIFigure
        function Close(app, event)
            try
                fclose(app.Arduino);
            catch                
            end
            pause(0.5);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BreathingSensorUIFigure = uifigure('Visible', 'off');
            app.BreathingSensorUIFigure.Name = 'Breathing Sensor';
            app.BreathingSensorUIFigure.CloseRequestFcn = createCallbackFcn(app, @Close, true);
            app.UIAxes = uiaxes(app.BreathingSensorUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Volts')
            app.UIAxes.XLim = [0 5];
            app.UIAxes.YLim = [0 5];
            app.RateHzLabel = uilabel(app.BreathingSensorUIFigure);
            app.RateHzLabel.Text = 'Rate (Hz)';
            app.SampleRateEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.SampleRateEditField.Limits = [1 5000];
            app.SampleRateEditField.ValueChangedFcn = createCallbackFcn(app, @SetSampleRate, true);
            app.SampleRateEditField.Value = 1000;
            app.TimesEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimeEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.TimeEditField.Limits = [1 60];
            app.TimeEditField.Value = 5;
            app.UpperEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.UpperEditField.Limits = [0 5.1];
            app.UpperEditField.ValueChangedFcn = createCallbackFcn(app, @SetYRange, true);
            app.UpperEditField.Value = 5;
            app.LowerEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.LowerEditField.Limits = [-0.1 5];
            app.LowerEditField.ValueChangedFcn = createCallbackFcn(app, @SetYRange, true);
            app.AutoRangeCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.AutoRangeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoRangeYAxis, true);
            app.AutoRangeCheckBox.Text = 'Auto Range';
            app.FileEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.FileEditFieldLabel.Text = 'File';
            app.FileEditField = uieditfield(app.BreathingSensorUIFigure, 'text');
            app.FileSelectButton = uibutton(app.BreathingSensorUIFigure, 'push');
            app.FileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFile, true);
            app.FileSelectButton.Text = '...';
            app.PathTextAreaLabel = uilabel(app.BreathingSensorUIFigure);
            app.PathTextAreaLabel.Text = 'Path';
            app.PathTextArea = uitextarea(app.BreathingSensorUIFigure);
            app.PeakProminenceVEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.PeakProminenceVEditFieldLabel.Text = 'Peak Prominence (V)';
            app.PeakProminenceVEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.PeakProminenceVEditField.Limits = [0.005 5];
            app.PeakProminenceVEditField.Value = 0.2;
            app.MaxPeaksperMinuteLabel = uilabel(app.BreathingSensorUIFigure);
            app.MaxPeaksperMinuteLabel.Text = 'Max Peaks per Minute';
            app.MaxBreathingRateEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.MaxBreathingRateEditField.Limits = [10 300];
            app.MaxBreathingRateEditField.Value = 120;
            app.InvertCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.InvertCheckBox.Text = 'Invert';
            app.BreathingRateEditFieldLabel = uilabel(app.BreathingSensorUIFigure);
            app.BreathingRateEditFieldLabel.Text = 'Breathing Rate';
            app.BreathingRateEditField = uieditfield(app.BreathingSensorUIFigure, 'numeric');
            app.BreathingRateEditField.ValueDisplayFormat = '%11.1f';
            app.BreathingRateEditField.Editable = 'off';
            app.StartButton = uibutton(app.BreathingSensorUIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartStopRecording, true);
            app.StartButton.Text = 'Start';
            app.SavetoFileCheckBox = uicheckbox(app.BreathingSensorUIFigure);
            app.SavetoFileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeCallback, true);
            app.SavetoFileCheckBox.Text = 'Save to File';
            % Show the figure after all components are created
            app.BreathingSensorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BreathingSensor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BreathingSensorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BreathingSensorUIFigure)
        end
    end
end